classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WatermarkUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Button_2                        matlab.ui.control.Button
        WatermarkingusingWalshHadamardTransformLabel  matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        KeyGeneratorTab                 matlab.ui.container.Tab
        KeyGeneratorLabel               matlab.ui.control.Label
        DisplayPanel_4                  matlab.ui.container.Panel
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        ActionPanel_3                   matlab.ui.container.Panel
        ChooseWatermarkImageButton_3    matlab.ui.control.Button
        GenerateKeyButton               matlab.ui.control.Button
        SaveKeyButton                   matlab.ui.control.Button
        EmbedWatermarkTab               matlab.ui.container.Tab
        EmbedWatermarkLabel             matlab.ui.control.Label
        DisplayPanel                    matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        ActionPanel                     matlab.ui.container.Panel
        ChooseHostImageButton           matlab.ui.control.Button
        ChooseWatermarkImageButton      matlab.ui.control.Button
        ChooseKeyButton                 matlab.ui.control.Button
        EmbedWatermarkButton            matlab.ui.control.Button
        SaveWatermarkedImageButton      matlab.ui.control.Button
        ComparisonPanel                 matlab.ui.container.Panel
        PSNRButton                      matlab.ui.control.Button
        WPSNRButton                     matlab.ui.control.Button
        SSIMButton                      matlab.ui.control.Button
        NCButton                        matlab.ui.control.Button
        VisualButton                    matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        ExtractWatermarkTab             matlab.ui.container.Tab
        ExtractWatermarkLabel           matlab.ui.control.Label
        DisplayPanel_2                  matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        ActionPanel_2                   matlab.ui.container.Panel
        ChooseWatermarkedImageButton    matlab.ui.control.Button
        ChooseKeyButton_2               matlab.ui.control.Button
        ExtractWatermarkButton          matlab.ui.control.Button
        SaveExtractedWatermarkImageButton  matlab.ui.control.Button
        ChooseOriginalWatermarkImageButton  matlab.ui.control.Button
        ComparisonPanel_2               matlab.ui.container.Panel
        PSNRButton_2                    matlab.ui.control.Button
        WPSNRButton_2                   matlab.ui.control.Button
        SSIMButton_2                    matlab.ui.control.Button
        NCButton_2                      matlab.ui.control.Button
        VisualButton_2                  matlab.ui.control.Button
        HistogramButton_2               matlab.ui.control.Button
        AttackandExtractTab             matlab.ui.container.Tab
        AttackandExtractLabel           matlab.ui.control.Label
        DisplayPanel_3                  matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        NC1Label                        matlab.ui.control.Label
        PSNR1Label                      matlab.ui.control.Label
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        NC2Label                        matlab.ui.control.Label
        PSNR2Label                      matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        NC3Label                        matlab.ui.control.Label
        PSNR3Label                      matlab.ui.control.Label
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        NC4Label                        matlab.ui.control.Label
        PSNR4Label                      matlab.ui.control.Label
        ActionPanel_4                   matlab.ui.container.Panel
        ChooseWatermarkedImageButton_2  matlab.ui.control.Button
        ChooseWatermarkImageToCompareButton  matlab.ui.control.Button
        AttackButton                    matlab.ui.control.Button
        ExtractButton                   matlab.ui.control.Button
        ChooseKeyButton_3               matlab.ui.control.Button
        AttackListLabel                 matlab.ui.control.Label
        AttackListListBox               matlab.ui.control.ListBox
        AboutTab                        matlab.ui.container.Tab
        HowtouseTextAreaLabel           matlab.ui.control.Label
        HowtouseTextArea                matlab.ui.control.TextArea
        ContactTextAreaLabel            matlab.ui.control.Label
        ContactTextArea                 matlab.ui.control.TextArea
        AboutLabel                      matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        AuthorswordTextAreaLabel        matlab.ui.control.Label
        AuthorswordTextArea             matlab.ui.control.TextArea
        AdvertisementTab                matlab.ui.container.Tab
        Button_5                        matlab.ui.control.Button
        AdvertisementLabel              matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        ResumeButton                    matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Global variables (Bien toan cuc)
        WatermarkImageFullName % Path of WatermarkImage
        WatermarkImage % Matrices of WatermarkImage
        KeyFullName % Key Path
        Key % Key matrices
        HostImageFullName % Path of Host Image
        HostImage % Host Image matrices
        WatermarkedImage % Watermarked Image
        WatermarkedImageFullName % Watermarked Image path
        ExtractedWatermarkImageFullName % Extracted Watermark Image Path
        ExtractedWatermarkImage % Extracted Watermark Image matrices
        AtkImg_1; % Attacked Image #1
        AtkImg_2; % Attacked Image #2
        AtkImg_3; % Attacked Image #3
        AtkImg_4; % Attacked Image #4
        ExtAtkImg_1 % Extracted Watermark of Attacked Image #1
        ExtAtkImg_2 % Extracted Watermark of Attacked Image #2
        ExtAtkImg_3 % Extracted Watermark of Attacked Image #3
        ExtAtkImg_4 % Extracted Watermark of Attacked Image #4
    end
    % 　    /O/ /O/
    % 　   /､/ /､/
    %  ―丶/_/_/_/丶
    %  |　 |┃　 ┃|
    %  |___|.-*-.|
    %  | |つ￣￣|つ
    % ∝| |＿＿＿|
    %     連装砲

    methods (Access = private)

        % Button pushed function: ChooseWatermarkImageButton_3
        function ChooseWatermarkImageButton_3Pushed(app, event)
            % Add path of subfolder
            addpath(genpath(pwd));
            % Play sound
            playFile('Osoi.ogg');
            % Choose Watermark Button of Key Generator Tab
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open'); % Open windows to select image
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a image');
            else
                app.WatermarkImage = imread(app.WatermarkImageFullName); % Read Watermark Image and save to public properties
                I = imshow(app.WatermarkImage, 'Parent', app.UIAxes_13); % Show Watermark Image to UIAxes_13
                % Set limits of axes
                app.UIAxes_13.XLim = [0 I.XData(2)];
                app.UIAxes_13.YLim = [0 I.YData(2)];
                app.GenerateKeyButton.Enable = 'on';
            end
        end

        % Button pushed function: GenerateKeyButton
        function GenerateKeyButtonPushed(app, event)
            % Generate Key Button of Key Generator Tab
            try
                % Play sound
                playFile('Hairimasu.ogg');
                % Get size of Watermark Image
                [WatermarkImageRows, WatermarkImageColumns, WatermarkImageNumberOfColorBands] = size(app.WatermarkImage);
                % Generate key by scrambling all the pixels and save the
                % scarmble order to variable key
                app.Key = randperm(WatermarkImageRows*WatermarkImageColumns);
                % Show the scrambled watermark image
                ScrambledImage = scrambledImage(app.WatermarkImage,app.Key); % scramble watermark image
                I = imshow(ScrambledImage, 'Parent', app.UIAxes_14); % Show Scrambled Watermark Image to UIAxes_14
                % Set limits of axes
                app.UIAxes_14.XLim = [0 I.XData(2)];
                app.UIAxes_14.YLim = [0 I.YData(2)];
                % Enable Save Key Button
                app.SaveKeyButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: SaveKeyButton
        function SaveKeyButtonPushed(app, event)
            % Save Key Button of Key Generator Tab
            try
                % Play sound
                playFile('Save.ogg');
                [filename,pathname]=uiputfile({'*.txt;*.mat'},'Save Key','./MyKey.txt'); % Open window to save file
                var = strcat(pathname,filename); % store file path to variable "var"
                Key = app.Key; % Save function not allow to use global variable so we create "Key" to store "app.Key"
                save(var, 'Key', '-ascii', '-tabs'); % Save key
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: ChooseHostImageButton
        function ChooseHostImageButtonPushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Host Image to Open');
            app.HostImageFullName = [filepath, filename];
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a file');
            else
                app.HostImage = imread(app.HostImageFullName); %host image
                I = imshow(app.HostImage, 'Parent', app.UIAxes);
                app.ChooseWatermarkImageButton.Enable = 'on';
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
            end
        end

        % Button pushed function: ChooseWatermarkImageButton
        function ChooseWatermarkImageButtonPushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open');
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
            
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                if (checkWatermarkSize(app.HostImageFullName,app.WatermarkImageFullName) == false)
                    f = errordlg('Size too large');
                else
                    app.WatermarkImage = imread(app.WatermarkImageFullName);
                    I=imshow(app.WatermarkImage, 'Parent', app.UIAxes_2);
                    app.ChooseKeyButton.Enable = 'on';
                    % Set limits of axes
                    app.UIAxes_2.XLim = [0 I.XData(2)];
                    app.UIAxes_2.YLim = [0 I.YData(2)];
                end
            end
        end

        % Button pushed function: ChooseKeyButton
        function ChooseKeyButtonPushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Open window to select key file
            [filename,filepath] = uigetfile({'*.txt;*.mat;'}, 'Select key to Open');
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a file');
            else
                app.KeyFullName = [filepath, filename];
                %if ((isequal(app.fullname_2,0)==0) && (isequal(app.fullname,0)==0) && (isequal(app.fullname_3,0)==0))
                app.EmbedWatermarkButton.Enable='on';
                %end
            end
        end

        % Button pushed function: EmbedWatermarkButton
        function EmbedWatermarkButtonPushed(app, event)
            try
                % Play sound
                playFile('Hairimasu.ogg');
                % Progess window
                d = uiprogressdlg(app.WatermarkUIFigure,'Title','Please Wait (｀・ω・´)',...
                    'Message','Embedding watermark...','Indeterminate','on');
                check = true;
                while(check)
                    d.Message = 'Embedding watermark...( ﾉｼ・ヮ・)ﾉｼ三( ﾉｼ・ヮ・)ﾉｼ';
                    app.WatermarkedImage=Embed(app.WatermarkImageFullName,app.HostImageFullName,app.KeyFullName);
                    check=false;
                    % Play sound
                    playFile('End.ogg');
                end
                % Show title
                title(app.UIAxes, "Host Image");
                title(app.UIAxes_2, "Watermarked Image");
                % Show image
                I=imshow(app.HostImage, 'Parent', app.UIAxes);
                I_2=imshow(app.WatermarkedImage, 'Parent', app.UIAxes_2);
                % gioi han khung anh Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                app.UIAxes_2.XLim = [0 I_2.XData(2)];
                app.UIAxes_2.YLim = [0 I_2.YData(2)];
                % Show save button
                app.SaveWatermarkedImageButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: SaveWatermarkedImageButton
        function SaveWatermarkedImageButtonPushed(app, event)
            try
                % Play sound
                playFile('Save.ogg');
                [filename,pathname]=uiputfile({'*.jpg;*.jpeg;*.gif;*.tif;*.tiff;*.rgb;*.bmp;*.png',...
                    'All support image files';'*.jpg;*.jpeg','JPEG files(*.jpg;*.jpeg)';'*.gif','GIF files (*.gif)';...
                    '*.tif;*.tiff','TIFF files (*.tif;*.tiff)';'*.bmp','Bitmap files (*.bmp)';'*.png','PNG files (*.png)'},'Save Watermarked Image','Watermarked_Image.png');
                var = strcat(pathname,filename);
                imwrite(uint8(app.WatermarkedImage),var);
                app.WatermarkedImageFullName = var;
                
                %show button of next process
                app.PSNRButton.Enable = 'on';
                app.WPSNRButton.Enable = 'on';
                app.SSIMButton.Enable = 'on';
                app.NCButton.Enable = 'on';
                app.VisualButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: PSNRButton
        function PSNRButtonPushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Set visible and text of Label_9
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'PSNR';
            % Set visible and text of Label_10
            % Label 10 text calculate psnr of 2 image
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(psnr(imread(app.HostImageFullName), imread(app.WatermarkedImageFullName)));
        end

        % Button pushed function: WPSNRButton
        function WPSNRButtonPushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Add path of subfolder
            addpath(genpath(pwd));
            % Imread 2 images
            a=double(imread(app.HostImageFullName));
            b=double(imread(app.WatermarkedImageFullName));
            % Calculate WPSNR with custom function
            [psnr,wpsnr1]=wpsnr(a,b);
            % Set text
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'WPSNR';
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(wpsnr1);
        end

        % Button pushed function: SSIMButton
        function SSIMButtonPushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Set text
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'SSIM';
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(ssim(imread(app.WatermarkedImageFullName), imread(app.HostImageFullName)));
        end

        % Button pushed function: NCButton
        function NCButtonPushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Add path of subfolder
            addpath(genpath(pwd));
            % Calculate NC with custom function and set text
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'NC';
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(nc(app.WatermarkedImageFullName,app.HostImageFullName));
        end

        % Button pushed function: VisualButton
        function VisualButtonPushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Show anh
            I = imshow(app.HostImage, 'Parent', app.UIAxes);
            I_2 = imshow(app.WatermarkedImage, 'Parent', app.UIAxes_2);
            % gioi han khung anh
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.UIAxes_2.XLim = [0 I_2.XData(2)];
            app.UIAxes_2.YLim = [0 I_2.YData(2)];
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Imread 2 images
            a = imread(app.HostImageFullName);
            b = imread(app.WatermarkedImageFullName);
            % Clear Axes
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            % Show histogram of 2 images
            I = histogram(app.UIAxes, a);
            I_2 = histogram(app.UIAxes_2, b);
        end

        % Button pushed function: ChooseWatermarkedImageButton
        function ChooseWatermarkedImageButtonPushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermarked Image to Open');
            app.WatermarkedImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                app.WatermarkedImage = imread(app.WatermarkedImageFullName);
                I = imshow(app.WatermarkedImage, 'Parent', app.UIAxes_3);
                app.ChooseKeyButton_2.Enable = 'on';
                % Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];
            end
        end

        % Button pushed function: ChooseKeyButton_2
        function ChooseKeyButton_2Pushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Open window to select key file
            [filename,filepath] = uigetfile({'*.txt;*.mat;'}, 'Select key to Open');
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file');
            else
                app.KeyFullName = [filepath, filename];
                %if ((isequal(app.fullname_2,0)==0) && (isequal(app.fullname,0)==0) && (isequal(app.fullname_3,0)==0))
                app.ExtractWatermarkButton.Enable='on';
                %end
            end
        end

        % Button pushed function: ExtractWatermarkButton
        function ExtractWatermarkButtonPushed(app, event)
            try
                % Play sound
                playFile('Hairimasu.ogg');
                d = uiprogressdlg(app.WatermarkUIFigure,'Title','Please Wait (｀・ω・´)',...
                    'Message','Extracting watermark...','Indeterminate','on');
                check = true;
                while(check)
                    d.Message = 'Extracting watermark...( ﾉｼ・ヮ・)ﾉｼ三( ﾉｼ・ヮ・)ﾉｼ';
                    app.ExtractedWatermarkImage=extract(app.WatermarkedImageFullName, app.KeyFullName);
                    check = false;
                    % Play sound
                    playFile('End.ogg');
                end
                % Show title
                title(app.UIAxes_3, "Watermarked Image");
                title(app.UIAxes_4, "Extracted Watermark Image");
                % Show image
                I = imshow(app.WatermarkedImage, 'Parent', app.UIAxes_3);
                I_2 = imshow(app.ExtractedWatermarkImage, 'Parent', app.UIAxes_4);
                % gioi han khung anh Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];
                app.UIAxes_4.XLim = [0 I_2.XData(2)];
                app.UIAxes_4.YLim = [0 I_2.YData(2)];
                % Show save button
                app.SaveExtractedWatermarkImageButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: SaveExtractedWatermarkImageButton
        function SaveExtractedWatermarkImageButtonPushed(app, event)
            try
                % Play sound
                playFile('Save.ogg');
                [filename,pathname] = uiputfile({'*.jpg;*.jpeg;*.gif;*.tif;*.tiff;*.rgb;*.bmp;*.png',...
                    'All support image files';'*.jpg;*.jpeg','JPEG files(*.jpg;*.jpeg)';'*.gif','GIF files (*.gif)';...
                    '*.tif;*.tiff','TIFF files (*.tif;*.tiff)';'*.bmp','Bitmap files (*.bmp)';'*.png','PNG files (*.png)'},'Save Watermarked Image','Extracted_Watermark.png');
                var = strcat(pathname, filename);
                imwrite(uint8(app.ExtractedWatermarkImage), var);
                app.ExtractedWatermarkImageFullName = var;
                %show button of next process
                app.ChooseOriginalWatermarkImageButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: ChooseOriginalWatermarkImageButton
        function ChooseOriginalWatermarkImageButtonPushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open');
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                app.WatermarkImage = imread(app.WatermarkImageFullName);
                I = imshow(app.WatermarkImage, 'Parent', app.UIAxes_3);
                title(app.UIAxes_3, "Original Watermark Image");
                % Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];
                % Enable comparison button
                app.PSNRButton_2.Enable = 'on';
                app.WPSNRButton_2.Enable = 'on';
                app.SSIMButton_2.Enable = 'on';
                app.NCButton_2.Enable = 'on';
                app.VisualButton_2.Enable = 'on';
                app.HistogramButton_2.Enable = 'on';
            end
        end

        % Button pushed function: PSNRButton_2
        function PSNRButton_2Pushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Set visible and text of Label_11
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'PSNR';
            % Set visible and text of Label_112
            % Label 12 text calculate psnr of 2 image
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(psnr(imread(app.WatermarkImageFullName), imread(app.ExtractedWatermarkImageFullName)));
        end

        % Button pushed function: WPSNRButton_2
        function WPSNRButton_2Pushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Add path of subfolder
            addpath(genpath(pwd));
            % Imread 2 images
            a=double(imread(app.WatermarkImageFullName));
            b=double(imread(app.ExtractedWatermarkImageFullName));
            % Calculate WPSNR with custom function
            [psnr,wpsnr1]=wpsnr(a,b);
            % Set text
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'WPSNR';
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(wpsnr1);
        end

        % Button pushed function: SSIMButton_2
        function SSIMButton_2Pushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Set text
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'SSIM';
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(ssim(imread(app.ExtractedWatermarkImageFullName), imread(app.WatermarkImageFullName)));
        end

        % Button pushed function: NCButton_2
        function NCButton_2Pushed(app, event)
            % Add path of subfolder
            addpath(genpath(pwd));
            % Play sound
            playFile('Ou.ogg');
            % Calculate NC with custom function and set text
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'NC';
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(nc(app.ExtractedWatermarkImageFullName,app.WatermarkImageFullName));
        end

        % Button pushed function: VisualButton_2
        function VisualButton_2Pushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Show anh
            I = imshow(app.WatermarkImage, 'Parent', app.UIAxes_3);
            I_2 = imshow(app.ExtractedWatermarkImage, 'Parent', app.UIAxes_4);
            % gioi han khung anh
            app.UIAxes_3.XLim = [0 I.XData(2)];
            app.UIAxes_3.YLim = [0 I.YData(2)];
            app.UIAxes_4.XLim = [0 I_2.XData(2)];
            app.UIAxes_4.YLim = [0 I_2.YData(2)];
        end

        % Button pushed function: HistogramButton_2
        function HistogramButton_2Pushed(app, event)
            % Play sound
            playFile('Ou.ogg');
            % Read 2 images
            a = imread(app.WatermarkImageFullName);
            b = imread(app.ExtractedWatermarkImageFullName);
            % Clear Axes
            cla(app.UIAxes_3,'reset');
            cla(app.UIAxes_4,'reset');
            % Show histogram of 2 images
            I = histogram(app.UIAxes_3, a);
            I_2 = histogram(app.UIAxes_4, b);
        end

        % Value changed function: AttackListListBox
        function AttackListListBoxValueChanged(app, event)
            value = app.AttackListListBox.Value;
            %app.NC1Label.Text = value;
        end

        % Button pushed function: AttackButton
        function AttackButtonPushed(app, event)
            % Generate path
            addpath(genpath(pwd));
            % Play sound
            playFile('Ou.ogg');
            try
                switch app.AttackListListBox.Value
                    case 'Rotate' % Rotate attack
                        % First AXES
                        % Show image (we don't need to resize image if we use 'crop' option)
                        I = imshow(imrotate(app.WatermarkedImage, 10,'crop'), 'Parent', app.UIAxes_5);
                        title(app.UIAxes_5, "Rotate attack 10 degree"); % Change title
                        app.AtkImg_1 = rotateAtk(app.WatermarkedImageFullName, 10);
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image (we don't need to resize image if we use 'crop' option)
                        I = imshow(imrotate(app.WatermarkedImage, 20,'crop'), 'Parent', app.UIAxes_7);
                        title(app.UIAxes_7, "Rotate attack 20 degree"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        app.AtkImg_2 = rotateAtk(app.WatermarkedImageFullName, 20);
                        % Third AXES
                        % Show image (we don't need to resize image if we use 'crop' option)
                        I = imshow(imrotate(app.WatermarkedImage, 30,'crop'), 'Parent', app.UIAxes_9);
                        title(app.UIAxes_9, "Rotate attack 30 degree"); % Change title
                        
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        app.AtkImg_3 = rotateAtk(app.WatermarkedImageFullName, 30);
                        
                        % Fourth AXES
                        % Show image (we don't need to resize image if we use 'crop' option)
                        I = imshow(imrotate(app.WatermarkedImage, 40,'crop'), 'Parent', app.UIAxes_11);
                        title(app.UIAxes_11, "Rotate attack 40 degree"); % Change title
                        app.AtkImg_4 = rotateAtk(app.WatermarkedImageFullName, 40);
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Resize' % Scale Attack
                        % First AXES
                        % Show image (we need to turn off anti aliasing)
                        I = imshow(imresize(app.WatermarkedImage, 1.25, 'nearest','Antialiasing',false), 'Parent', app.UIAxes_5);
                        title(app.UIAxes_5, "Scale 1.25"); % Change title
                        app.AtkImg_1 = resizeAtk(app.WatermarkedImageFullName, 1.25);
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image (we need to turn off anti aliasing)
                        I = imshow(imresize(app.WatermarkedImage, 1.5, 'nearest','Antialiasing',false), 'Parent', app.UIAxes_7);
                        title(app.UIAxes_7, "Scale 1.5"); % Change title
                        app.AtkImg_2 = resizeAtk(app.WatermarkedImageFullName, 1.5);
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        % Show image (we need to turn off anti aliasing)
                        I = imshow(imresize(app.WatermarkedImage, 1.75, 'nearest','Antialiasing',false), 'Parent', app.UIAxes_9);
                        title(app.UIAxes_9, "Scale 1.75"); % Change title
                        app.AtkImg_3 = resizeAtk(app.WatermarkedImageFullName, 1.75);
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        % Show image (we need to turn off anti aliasing)
                        I = imshow(imresize(app.WatermarkedImage, 2, 'nearest','Antialiasing',false), 'Parent', app.UIAxes_11);
                        title(app.UIAxes_11, "Scale 2"); % Change title
                        app.AtkImg_4 = resizeAtk(app.WatermarkedImageFullName, 2);
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Crop' % Crop attack
                        % First AXES
                        app.AtkImg_1 = cropAtk(app.WatermarkedImageFullName, 25); % Attack
                        I = imshow(app.AtkImg_1, 'Parent', app.UIAxes_5); % Show image
                        title(app.UIAxes_5, "Crop attack 25% vertical"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        app.AtkImg_2 = cropAtk(app.WatermarkedImageFullName, 40); % Attack
                        I = imshow(app.AtkImg_2, 'Parent', app.UIAxes_7); % Show image
                        title(app.UIAxes_7, "Crop attack 40% vertical"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        app.AtkImg_3 = cropAtk(app.WatermarkedImageFullName, 50); % Attack
                        I = imshow(app.AtkImg_3, 'Parent', app.UIAxes_9); % Show image
                        title(app.UIAxes_9, "Crop attack 50% vertical"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        app.AtkImg_4 = cropAtk(app.WatermarkedImageFullName, 60); % Attack
                        I = imshow(app.AtkImg_4, 'Parent', app.UIAxes_11); % Show image
                        title(app.UIAxes_11, "Crop attack 40% vertical"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Salt and Pepper'
                        % First AXES
                        % Show image
                        app.AtkImg_1 = sapAtk(app.WatermarkedImageFullName, 0.01);
                        I = imshow(app.AtkImg_1, 'Parent', app.UIAxes_5); % Show image
                        title(app.UIAxes_5, "Noise 0.01"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image
                        app.AtkImg_2 = sapAtk(app.WatermarkedImageFullName, 0.02);
                        I = imshow(app.AtkImg_2, 'Parent', app.UIAxes_7); % Show image
                        title(app.UIAxes_7, "Noise 0.02"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        % Show image
                        app.AtkImg_3 = sapAtk(app.WatermarkedImageFullName, 0.03);
                        I = imshow(app.AtkImg_3, 'Parent', app.UIAxes_9); % Show image
                        title(app.UIAxes_9, "Noise 0.03"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        % Show image
                        app.AtkImg_4 = sapAtk(app.WatermarkedImageFullName, 0.04);
                        I = imshow(app.AtkImg_4, 'Parent', app.UIAxes_11); % Show image
                        title(app.UIAxes_11, "Noise 0.04"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Median filtering'
                        % First AXES
                        % Show image
                        app.AtkImg_1 = medFilAtk(app.WatermarkedImageFullName, 3);
                        I = imshow(app.AtkImg_1, 'Parent', app.UIAxes_5); % Show image
                        title(app.UIAxes_5, "Median Filtering 3x1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image
                        app.AtkImg_2 = medFilAtk(app.WatermarkedImageFullName, 5);
                        I = imshow(app.AtkImg_2, 'Parent', app.UIAxes_7); % Show image
                        title(app.UIAxes_7, "Median Filtering 5x1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        % Show image
                        app.AtkImg_3 = medFilAtk(app.WatermarkedImageFullName, 7);
                        I = imshow(app.AtkImg_3, 'Parent', app.UIAxes_9); % Show image
                        title(app.UIAxes_9, "Median Filtering 3x1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        % Show image
                        app.AtkImg_4 = medFilAtk(app.WatermarkedImageFullName, 9);
                        I = imshow(app.AtkImg_4, 'Parent', app.UIAxes_11); % Show image
                        title(app.UIAxes_11, "Median Filtering 9x1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Brighten'
                        % First AXES
                        % Show image
                        app.AtkImg_1 = brightenAtk(app.WatermarkedImageFullName, 10);
                        I = imshow(app.AtkImg_1, 'Parent', app.UIAxes_5); % Show image
                        title(app.UIAxes_5, "Brighten 10 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image
                        app.AtkImg_2 = brightenAtk(app.WatermarkedImageFullName, 30);
                        I = imshow(app.AtkImg_2, 'Parent', app.UIAxes_7); % Show image
                        title(app.UIAxes_7, "Brighten 30 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        % Show image
                        app.AtkImg_3 = brightenAtk(app.WatermarkedImageFullName, 60);
                        I = imshow(app.AtkImg_3, 'Parent', app.UIAxes_9); % Show image
                        title(app.UIAxes_9, "Brighten 60 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        % Show image
                        app.AtkImg_4 = brightenAtk(app.WatermarkedImageFullName, 90);
                        I = imshow(app.AtkImg_4, 'Parent', app.UIAxes_11); % Show image
                        title(app.UIAxes_11, "Brighten 90 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Darken'
                        % First AXES
                        % Show image
                        app.AtkImg_1 = darkenAtk(app.WatermarkedImageFullName, 10);
                        I = imshow(app.AtkImg_1, 'Parent', app.UIAxes_5); % Show image
                        title(app.UIAxes_5, "Darken 10 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image
                        app.AtkImg_2 = darkenAtk(app.WatermarkedImageFullName, 30);
                        I = imshow(app.AtkImg_2, 'Parent', app.UIAxes_7); % Show image
                        title(app.UIAxes_7, "Darken 30 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        % Show image
                        app.AtkImg_3 = darkenAtk(app.WatermarkedImageFullName, 60);
                        I = imshow(app.AtkImg_3, 'Parent', app.UIAxes_9); % Show image
                        title(app.UIAxes_9, "Darken 60 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        % Show image
                        app.AtkImg_4 = darkenAtk(app.WatermarkedImageFullName, 90);
                        I = imshow(app.AtkImg_4, 'Parent', app.UIAxes_11); % Show image
                        title(app.UIAxes_11, "Darken 90 value"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                        
                    case 'Compress'
                        % First AXES
                        % Show image
                        app.AtkImg_1 = compressAtk(app.WatermarkedImageFullName, 'compress_lossless.jp2', 'lossless', 1);
                        I = imshow(app.AtkImg_1, 'Parent', app.UIAxes_5); % Show image
                        title(app.UIAxes_5, "Lossless compress"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_5.XLim = [0 I.XData(2)];
                        app.UIAxes_5.YLim = [0 I.YData(2)];
                        
                        % Second AXES
                        % Show image
                        app.AtkImg_2 = compressAtk(app.WatermarkedImageFullName, 'compress_lossy_2_1.jp2', 'lossy', 2);
                        I = imshow(app.AtkImg_2, 'Parent', app.UIAxes_7); % Show image
                        title(app.UIAxes_7, "Lossy compress ratio 2:1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_7.XLim = [0 I.XData(2)];
                        app.UIAxes_7.YLim = [0 I.YData(2)];
                        
                        % Third AXES
                        % Show image
                        app.AtkImg_3 = compressAtk(app.WatermarkedImageFullName, 'compress_lossy_3_1.jp2', 'lossy', 3);
                        I = imshow(app.AtkImg_3, 'Parent', app.UIAxes_9); % Show image
                        title(app.UIAxes_9, "Lossy compress ratio 3:1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_9.XLim = [0 I.XData(2)];
                        app.UIAxes_9.YLim = [0 I.YData(2)];
                        
                        % Fourth AXES
                        % Show image
                        app.AtkImg_4 = compressAtk(app.WatermarkedImageFullName, 'compress_lossy_4_1.jp2', 'lossy', 4);
                        I = imshow(app.AtkImg_4, 'Parent', app.UIAxes_11); % Show image
                        title(app.UIAxes_11, "Lossy compress ratio 4:1"); % Change title
                        % gioi han khung anh Set limits of axes
                        app.UIAxes_11.XLim = [0 I.XData(2)];
                        app.UIAxes_11.YLim = [0 I.YData(2)];
                    otherwise
                        msgbox("Wrong choice!!!","Error");
                end
                app.ExtractButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: ChooseWatermarkedImageButton_2
        function ChooseWatermarkedImageButton_2Pushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermarked Image to Open');
            app.WatermarkedImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                app.WatermarkedImage = imread(app.WatermarkedImageFullName);
                app.ChooseKeyButton_3.Enable = 'on';
            end
        end

        % Button pushed function: ChooseKeyButton_3
        function ChooseKeyButton_3Pushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Open window to select key file
            [filename,filepath] = uigetfile({'*.txt;*.mat;'}, 'Select key to Open');
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file');
            else
                app.KeyFullName = [filepath, filename];
                %if ((isequal(app.fullname_2,0)==0) && (isequal(app.fullname,0)==0) && (isequal(app.fullname_3,0)==0))
                app.ChooseWatermarkImageToCompareButton.Enable='on';
                %end
            end
        end

        % Button pushed function: ChooseWatermarkImageToCompareButton
        function ChooseWatermarkImageToCompareButtonPushed(app, event)
            % Play sound
            playFile('Osoi.ogg');
            % Choose watermark image
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open');
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                app.WatermarkImage = imread(app.WatermarkImageFullName);
                app.AttackListListBox.Enable = 'on';
                app.AttackButton.Enable = 'on';
            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            try
                % Play sound
                playFile('Hairimasu.ogg');
                % Progess window
                d = uiprogressdlg(app.WatermarkUIFigure,'Title','Please Wait (｀・ω・´)',...
                    'Message','Extracting watermark. The process could take very long time so be patient...','Indeterminate','on');
                % Extract rotate attack
                d.Message = 'Extracting watermark of image #1. The process could take very long time so be patient...';
                app.ExtAtkImg_1 = extractAtk(app.AtkImg_1, app.KeyFullName);
                d.Message = 'Extracting watermark of image #2. The process could take very long time so be patient...';
                app.ExtAtkImg_2 = extractAtk(app.AtkImg_2, app.KeyFullName);
                d.Message = 'Extracting watermark of image #3. The process could take very long time so be patient...';
                app.ExtAtkImg_3 = extractAtk(app.AtkImg_3, app.KeyFullName);
                d.Message = 'Extracting watermark of image #4. The process could take very long time so be patient...';
                app.ExtAtkImg_4 = extractAtk(app.AtkImg_4, app.KeyFullName);
                % Show image
                I = imshow(app.ExtAtkImg_1, 'Parent', app.UIAxes_6);
                I_2 = imshow(app.ExtAtkImg_2, 'Parent', app.UIAxes_8);
                I_3 = imshow(app.ExtAtkImg_3, 'Parent', app.UIAxes_10);
                I_4 = imshow(app.ExtAtkImg_4, 'Parent', app.UIAxes_12);
                % gioi han khung anh Set limits of axes
                app.UIAxes_6.XLim = [0 I.XData(2)];
                app.UIAxes_6.YLim = [0 I.YData(2)];
                app.UIAxes_8.XLim = [0 I_2.XData(2)];
                app.UIAxes_8.YLim = [0 I_2.YData(2)];
                app.UIAxes_10.XLim = [0 I_3.XData(2)];
                app.UIAxes_10.YLim = [0 I_3.YData(2)];
                app.UIAxes_12.XLim = [0 I_4.XData(2)];
                app.UIAxes_12.YLim = [0 I_4.YData(2)];
                % Show NC and PSNR
                app.NC1Label.Text = string(nc2(app.ExtAtkImg_1, app.WatermarkImageFullName));
                app.PSNR1Label.Text = string(psnr(app.ExtAtkImg_1, imread(app.WatermarkImageFullName)));
                app.NC2Label.Text = string(nc2(app.ExtAtkImg_2,app.WatermarkImageFullName));
                app.PSNR2Label.Text = string(psnr(app.ExtAtkImg_2, imread(app.WatermarkImageFullName)));
                app.NC3Label.Text = string(nc2(app.ExtAtkImg_3,app.WatermarkImageFullName));
                app.PSNR3Label.Text = string(psnr(app.ExtAtkImg_3, imread(app.WatermarkImageFullName)));
                app.NC4Label.Text = string(nc2(app.ExtAtkImg_4,app.WatermarkImageFullName));
                app.PSNR4Label.Text = string(psnr(app.ExtAtkImg_4, imread(app.WatermarkImageFullName)));
                % Play sound
                playFile('End.ogg');
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % logo ACT button
            web('http://actvn.edu.vn/', '-browser');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Logo BacDau button
            web('https://haidang10897.github.io/archive/', '-browser');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Shimakaze picture button
            web('https://en.wikipedia.org/wiki/Japanese_destroyer_Shimakaze_(1942)', '-browser');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % Banner ACT button
            web('http://actvn.edu.vn/thong-bao-khoa-attt-thong-bao-vv-bao-cao-thuc-tap-tot-nghiep-cho-at13clc-va-do-an-tot-nghiep-cho-at13clc-va-at12-dot-2-160485.html', '-browser');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % Ads KC
            web('http://www.dmm.com/netgame/feature/kancolle.html', '-browser');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % Ads KC3kai
            web('https://github.com/KC3Kai/KC3Kai', '-browser');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % Ads Olshiro
            web('http://olshiro.net/', '-browser');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % Ads GMOW
            web('https://twitter.com/_GMOW', '-browser');
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % Ads Hanoi HEMA
            web('https://www.facebook.com/HanoiHEMAClub', '-browser');
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                global player
                [y, Fs] = audioread('Kikan.mp3');
                player = audioplayer(y,Fs);
                play(player);
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global player
            pause(player);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            global player
            resume(player);
        end

        % Close request function: WatermarkUIFigure
        function WatermarkUIFigureCloseRequest(app, event)
            % Clear all variable when close program
            delete(app);
            delete(gcf);
            clearvars;
            close all;
            clear all;
            clear global;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % Music Kikan
            web('https://www.amazon.co.jp/%E5%B8%B0%E9%82%84-%E8%A5%BF%E6%B2%A2%E5%B9%B8%E5%A5%8F/dp/B01LZXY80D/ref=sr_1_1?ie=UTF8&qid=1500187473&sr=8-1&keywords=kikan', '-browser');
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % Ads CG Persia
            web('https://cgpersia.com/', '-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WatermarkUIFigure = uifigure;
            app.WatermarkUIFigure.Name = 'Watermark';
            app.WatermarkUIFigure.CloseRequestFcn = createCallbackFcn(app, @WatermarkUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.WatermarkUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Button = uibutton(app.HomeTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'KMA.png';
            app.Button.Text = '';
            app.TextAreaLabel = uilabel(app.HomeTab);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.HomeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Project: Researching a robust and imperceptible blind color image watermarking using Walsh Hadamard Transform'; ''; 'Author: Tran Hai Dang - AT12ET - AT120515 - Academy of Cryptography Techniques'; ''; 'Instructor: Tran Thi Xuyen - Academy of Cryptography Techniques'; ''; 'Reference: An effective robust and imperceptible blind color image watermarking using WHT,'; ' K. Prabha, I. Shatheesh Sam '; ''};
            app.Button_2 = uibutton(app.HomeTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'zekamashi_large.png';
            app.Button_2.Text = '';
            app.WatermarkingusingWalshHadamardTransformLabel = uilabel(app.HomeTab);
            app.WatermarkingusingWalshHadamardTransformLabel.Text = 'Watermarking using Walsh Hadamard Transform';
            app.Button_3 = uibutton(app.HomeTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'hvmm.png';
            app.Button_3.Text = '';
            app.KeyGeneratorTab = uitab(app.TabGroup);
            app.KeyGeneratorTab.Title = 'Key Generator';
            app.KeyGeneratorLabel = uilabel(app.KeyGeneratorTab);
            app.KeyGeneratorLabel.Text = 'Key Generator';
            app.DisplayPanel_4 = uipanel(app.KeyGeneratorTab);
            app.DisplayPanel_4.Title = 'Display';
            app.UIAxes_13 = uiaxes(app.DisplayPanel_4);
            title(app.UIAxes_13, 'Original Watermark Image')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.DisplayPanel_4);
            title(app.UIAxes_14, 'Scrambled Watermark Image')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.ActionPanel_3 = uipanel(app.KeyGeneratorTab);
            app.ActionPanel_3.Title = 'Action';
            app.ChooseWatermarkImageButton_3 = uibutton(app.ActionPanel_3, 'push');
            app.ChooseWatermarkImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkImageButton_3Pushed, true);
            app.ChooseWatermarkImageButton_3.Text = {'Choose Watermark '; 'Image'};
            app.GenerateKeyButton = uibutton(app.ActionPanel_3, 'push');
            app.GenerateKeyButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateKeyButtonPushed, true);
            app.GenerateKeyButton.Enable = 'off';
            app.GenerateKeyButton.Text = 'Generate Key';
            app.SaveKeyButton = uibutton(app.ActionPanel_3, 'push');
            app.SaveKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKeyButtonPushed, true);
            app.SaveKeyButton.Enable = 'off';
            app.SaveKeyButton.Text = 'Save Key';
            app.EmbedWatermarkTab = uitab(app.TabGroup);
            app.EmbedWatermarkTab.Title = 'Embed Watermark';
            app.EmbedWatermarkLabel = uilabel(app.EmbedWatermarkTab);
            app.EmbedWatermarkLabel.Text = 'Embed Watermark';
            app.DisplayPanel = uipanel(app.EmbedWatermarkTab);
            app.DisplayPanel.Title = 'Display';
            app.UIAxes = uiaxes(app.DisplayPanel);
            title(app.UIAxes, 'Host Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.DisplayPanel);
            title(app.UIAxes_2, 'Watermark Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label_9 = uilabel(app.DisplayPanel);
            app.Label_9.Visible = 'off';
            app.Label_10 = uilabel(app.DisplayPanel);
            app.Label_10.Visible = 'off';
            app.ActionPanel = uipanel(app.EmbedWatermarkTab);
            app.ActionPanel.Title = 'Action';
            app.ChooseHostImageButton = uibutton(app.ActionPanel, 'push');
            app.ChooseHostImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseHostImageButtonPushed, true);
            app.ChooseHostImageButton.Text = 'Choose Host Image';
            app.ChooseWatermarkImageButton = uibutton(app.ActionPanel, 'push');
            app.ChooseWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkImageButtonPushed, true);
            app.ChooseWatermarkImageButton.Enable = 'off';
            app.ChooseWatermarkImageButton.Text = {'Choose Watermark '; 'Image'};
            app.ChooseKeyButton = uibutton(app.ActionPanel, 'push');
            app.ChooseKeyButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseKeyButtonPushed, true);
            app.ChooseKeyButton.Enable = 'off';
            app.ChooseKeyButton.Text = 'Choose Key';
            app.EmbedWatermarkButton = uibutton(app.ActionPanel, 'push');
            app.EmbedWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedWatermarkButtonPushed, true);
            app.EmbedWatermarkButton.Enable = 'off';
            app.EmbedWatermarkButton.Text = 'Embed Watermark';
            app.SaveWatermarkedImageButton = uibutton(app.ActionPanel, 'push');
            app.SaveWatermarkedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWatermarkedImageButtonPushed, true);
            app.SaveWatermarkedImageButton.Enable = 'off';
            app.SaveWatermarkedImageButton.Text = {'Save Watermarked '; 'Image'};
            app.ComparisonPanel = uipanel(app.EmbedWatermarkTab);
            app.ComparisonPanel.Title = 'Comparison';
            app.PSNRButton = uibutton(app.ComparisonPanel, 'push');
            app.PSNRButton.ButtonPushedFcn = createCallbackFcn(app, @PSNRButtonPushed, true);
            app.PSNRButton.Enable = 'off';
            app.PSNRButton.Text = 'PSNR';
            app.WPSNRButton = uibutton(app.ComparisonPanel, 'push');
            app.WPSNRButton.ButtonPushedFcn = createCallbackFcn(app, @WPSNRButtonPushed, true);
            app.WPSNRButton.Enable = 'off';
            app.WPSNRButton.Text = 'WPSNR';
            app.SSIMButton = uibutton(app.ComparisonPanel, 'push');
            app.SSIMButton.ButtonPushedFcn = createCallbackFcn(app, @SSIMButtonPushed, true);
            app.SSIMButton.Enable = 'off';
            app.SSIMButton.Text = 'SSIM';
            app.NCButton = uibutton(app.ComparisonPanel, 'push');
            app.NCButton.ButtonPushedFcn = createCallbackFcn(app, @NCButtonPushed, true);
            app.NCButton.Enable = 'off';
            app.NCButton.Text = 'NC';
            app.VisualButton = uibutton(app.ComparisonPanel, 'push');
            app.VisualButton.ButtonPushedFcn = createCallbackFcn(app, @VisualButtonPushed, true);
            app.VisualButton.Enable = 'off';
            app.VisualButton.Text = 'Visual';
            app.HistogramButton = uibutton(app.ComparisonPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.ExtractWatermarkTab = uitab(app.TabGroup);
            app.ExtractWatermarkTab.Title = 'Extract Watermark';
            app.ExtractWatermarkLabel = uilabel(app.ExtractWatermarkTab);
            app.ExtractWatermarkLabel.Text = 'Extract Watermark';
            app.DisplayPanel_2 = uipanel(app.ExtractWatermarkTab);
            app.DisplayPanel_2.Title = 'Display';
            app.UIAxes_3 = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes_3, 'Watermarked Image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes_4, 'Extracted Watermark Image')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Label_11 = uilabel(app.DisplayPanel_2);
            app.Label_11.Visible = 'off';
            app.Label_12 = uilabel(app.DisplayPanel_2);
            app.Label_12.Visible = 'off';
            app.ActionPanel_2 = uipanel(app.ExtractWatermarkTab);
            app.ActionPanel_2.Title = 'Action';
            app.ChooseWatermarkedImageButton = uibutton(app.ActionPanel_2, 'push');
            app.ChooseWatermarkedImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkedImageButtonPushed, true);
            app.ChooseWatermarkedImageButton.Text = {'Choose Watermarked'; ' Image'};
            app.ChooseKeyButton_2 = uibutton(app.ActionPanel_2, 'push');
            app.ChooseKeyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseKeyButton_2Pushed, true);
            app.ChooseKeyButton_2.Enable = 'off';
            app.ChooseKeyButton_2.Text = 'Choose Key';
            app.ExtractWatermarkButton = uibutton(app.ActionPanel_2, 'push');
            app.ExtractWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractWatermarkButtonPushed, true);
            app.ExtractWatermarkButton.Enable = 'off';
            app.ExtractWatermarkButton.Text = 'Extract Watermark';
            app.SaveExtractedWatermarkImageButton = uibutton(app.ActionPanel_2, 'push');
            app.SaveExtractedWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExtractedWatermarkImageButtonPushed, true);
            app.SaveExtractedWatermarkImageButton.Enable = 'off';
            app.SaveExtractedWatermarkImageButton.Text = {'Save Extracted'; 'Watermark Image'};
            app.ChooseOriginalWatermarkImageButton = uibutton(app.ActionPanel_2, 'push');
            app.ChooseOriginalWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseOriginalWatermarkImageButtonPushed, true);
            app.ChooseOriginalWatermarkImageButton.Enable = 'off';
            app.ChooseOriginalWatermarkImageButton.Text = {'Choose Original'; 'Watermark Image'};
            app.ComparisonPanel_2 = uipanel(app.ExtractWatermarkTab);
            app.ComparisonPanel_2.Title = 'Comparison';
            app.PSNRButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.PSNRButton_2.ButtonPushedFcn = createCallbackFcn(app, @PSNRButton_2Pushed, true);
            app.PSNRButton_2.Enable = 'off';
            app.PSNRButton_2.Text = 'PSNR';
            app.WPSNRButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.WPSNRButton_2.ButtonPushedFcn = createCallbackFcn(app, @WPSNRButton_2Pushed, true);
            app.WPSNRButton_2.Enable = 'off';
            app.WPSNRButton_2.Text = 'WPSNR';
            app.SSIMButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.SSIMButton_2.ButtonPushedFcn = createCallbackFcn(app, @SSIMButton_2Pushed, true);
            app.SSIMButton_2.Enable = 'off';
            app.SSIMButton_2.Text = 'SSIM';
            app.NCButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.NCButton_2.ButtonPushedFcn = createCallbackFcn(app, @NCButton_2Pushed, true);
            app.NCButton_2.Enable = 'off';
            app.NCButton_2.Text = 'NC';
            app.VisualButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.VisualButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualButton_2Pushed, true);
            app.VisualButton_2.Enable = 'off';
            app.VisualButton_2.Text = 'Visual';
            app.HistogramButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.HistogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @HistogramButton_2Pushed, true);
            app.HistogramButton_2.Enable = 'off';
            app.HistogramButton_2.Text = 'Histogram';
            app.AttackandExtractTab = uitab(app.TabGroup);
            app.AttackandExtractTab.Title = 'Attack and Extract ';
            app.AttackandExtractLabel = uilabel(app.AttackandExtractTab);
            app.AttackandExtractLabel.Text = 'Attack and Extract ';
            app.DisplayPanel_3 = uipanel(app.AttackandExtractTab);
            app.DisplayPanel_3.Title = 'Display';
            app.UIAxes_5 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_5, 'Attacked Image #1')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_6, 'Extracted Watermark #1')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.NC1Label = uilabel(app.DisplayPanel_3);
            app.NC1Label.Text = 'NC #1';
            app.PSNR1Label = uilabel(app.DisplayPanel_3);
            app.PSNR1Label.Text = 'PSNR #1';
            app.UIAxes_7 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_7, 'Attacked Image #2')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_8, 'Extracted Watermark #2')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.NC2Label = uilabel(app.DisplayPanel_3);
            app.NC2Label.Text = 'NC #2';
            app.PSNR2Label = uilabel(app.DisplayPanel_3);
            app.PSNR2Label.Text = 'PSNR #2';
            app.UIAxes_9 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_9, 'Attacked Image #3')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_10, 'Extracted Watermark #3')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.NC3Label = uilabel(app.DisplayPanel_3);
            app.NC3Label.Text = 'NC #3';
            app.PSNR3Label = uilabel(app.DisplayPanel_3);
            app.PSNR3Label.Text = 'PSNR #3';
            app.UIAxes_11 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_11, 'Attacked Image #4')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_12, 'Extracted Watermark #4')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.NC4Label = uilabel(app.DisplayPanel_3);
            app.NC4Label.Text = 'NC #4';
            app.PSNR4Label = uilabel(app.DisplayPanel_3);
            app.PSNR4Label.Text = 'PSNR #4';
            app.ActionPanel_4 = uipanel(app.AttackandExtractTab);
            app.ActionPanel_4.Title = 'Action';
            app.ChooseWatermarkedImageButton_2 = uibutton(app.ActionPanel_4, 'push');
            app.ChooseWatermarkedImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkedImageButton_2Pushed, true);
            app.ChooseWatermarkedImageButton_2.Text = {'Choose Watermarked'; ' Image'};
            app.ChooseWatermarkImageToCompareButton = uibutton(app.ActionPanel_4, 'push');
            app.ChooseWatermarkImageToCompareButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkImageToCompareButtonPushed, true);
            app.ChooseWatermarkImageToCompareButton.Enable = 'off';
            app.ChooseWatermarkImageToCompareButton.Text = {'Choose Watermark'; 'Image To Compare'};
            app.AttackButton = uibutton(app.ActionPanel_4, 'push');
            app.AttackButton.ButtonPushedFcn = createCallbackFcn(app, @AttackButtonPushed, true);
            app.AttackButton.Enable = 'off';
            app.AttackButton.Text = 'Attack';
            app.ExtractButton = uibutton(app.ActionPanel_4, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Enable = 'off';
            app.ExtractButton.Text = 'Extract';
            app.ChooseKeyButton_3 = uibutton(app.ActionPanel_4, 'push');
            app.ChooseKeyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChooseKeyButton_3Pushed, true);
            app.ChooseKeyButton_3.Enable = 'off';
            app.ChooseKeyButton_3.Text = 'Choose Key';
            app.AttackListLabel = uilabel(app.ActionPanel_4);
            app.AttackListLabel.Enable = 'off';
            app.AttackListLabel.Text = {'Attack'; 'List'};
            app.AttackListListBox = uilistbox(app.ActionPanel_4);
            app.AttackListListBox.Items = {'Rotate', 'Resize', 'Crop', 'Salt and Pepper', 'Median filtering', 'Brighten', 'Darken', 'Compress'};
            app.AttackListListBox.ValueChangedFcn = createCallbackFcn(app, @AttackListListBoxValueChanged, true);
            app.AttackListListBox.Enable = 'off';
            app.AttackListListBox.Value = 'Rotate';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.HowtouseTextAreaLabel = uilabel(app.AboutTab);
            app.HowtouseTextAreaLabel.Text = {'How'; 'to '; 'use'};
            app.HowtouseTextArea = uitextarea(app.AboutTab);
            app.HowtouseTextArea.Editable = 'off';
            app.HowtouseTextArea.Value = {'######## Key Generator #########'; '1/ Choose Watermark Image'; '2/ Click "Generate Key" Button'; '3/ The Watermark Image scrambled using generated key will display on the right display. You can click "Generate Key" many time as long as you want.'; '4/ Click "Save Key" Button to save the key.'; ''; '######## Embed Process #########'; '1/ Choose Host Image'; '2/ Choose Watermark Image (Caution: Embed capacity of this method is 0.25bpp so you must choose wisely)'; '3/ Choose key'; '4/ Click "Embed" button'; '5/ Click "Save" button to save Watermarked Image'; '6/ You can compare the difference between Host Image and Watermarked Image by choose comparison button in "comparison group"'; ''; '######## Extract Process ########'; '1/ Click "Choose Watermarked Image" Button'; '2/ Click "Choose Key" Button'; '3/ Click "Extract Watermark" Button to begin extract watermark process'; '4/ Click "Save Extracted Watermark Image" Button to save watermark image that you''ve extracted'; '5/ Click "Choose Original Watermark Image" (This feature just add original watermark image to compare NC, PSNR,... and not do anything else)'; '6/ After that, you can choose the comparison type and see the result'; ''; '######## Attack and Extract #######'; '1/ Click "Choose Watermarked Image" Button'; '2/ Click "Choose Key" Button'; '3/ Click "Choose Original Watermark Image to compare" (This feature just add original watermark image to compare NC, PSNR,... and not do anything else)'; '4/ Choose attack type in "Attack List"'; '5/ Click "Attack" Button to begin attack'; '6/ Click "Extract" Button to begin extract watermark image after being attacked'; '7/ If you want to choose another attack, repeat step 4,5 and 6'; ''; '!!!!!!!!!! THANK YOU FOR USING THIS PROGRAM !!!!!!!!'; '(*≧ω≦*)	(*≧ω≦*)	(*≧ω≦*)	(*≧ω≦*)	'};
            app.ContactTextAreaLabel = uilabel(app.AboutTab);
            app.ContactTextAreaLabel.Text = 'Contact';
            app.ContactTextArea = uitextarea(app.AboutTab);
            app.ContactTextArea.Editable = 'off';
            app.ContactTextArea.Value = {'Github: haidang10897'; 'Facebook: tran.h.dang.54'; 'Twitter: haidang10897'; 'Steam: BoDut'; 'Gmail: haidangtran97@gmail.com'; 'Outlook: AT120515@actvn.edu.vn'; ''; ''};
            app.AboutLabel = uilabel(app.AboutTab);
            app.AboutLabel.Text = 'About';
            app.Button_4 = uibutton(app.AboutTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'shimakaze.png';
            app.Button_4.Text = '';
            app.AuthorswordTextAreaLabel = uilabel(app.AboutTab);
            app.AuthorswordTextAreaLabel.Text = {'Author''s'; 'word'};
            app.AuthorswordTextArea = uitextarea(app.AboutTab);
            app.AuthorswordTextArea.Editable = 'off';
            app.AuthorswordTextArea.Value = {'This is a open source project, feel free to copy or edit or do anything else. But remember keep the author''s information or mai waifu Shimakaze will come and kick ur @SS with main turrets and torpedoes, mai waifu iz the fastest destroyer so u can''t run away <(") '; 'For those who don''t who the F**K iz that, well, you can see to the right <(")'; ''};
            app.AdvertisementTab = uitab(app.TabGroup);
            app.AdvertisementTab.Title = 'Advertisement';
            app.Button_5 = uibutton(app.AdvertisementTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'bnr_kancolle_0.jpg';
            app.Button_5.Text = '';
            app.AdvertisementLabel = uilabel(app.AdvertisementTab);
            app.AdvertisementLabel.Text = 'Advertisement';
            app.Label2 = uilabel(app.AdvertisementTab);
            app.Label2.Text = {'Please like, support, donate,... to these club, group, project,... if you like. '; 'These groups are place where i participate in and they are special parts of my life  '};
            app.Button_6 = uibutton(app.AdvertisementTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'olshiro.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.AdvertisementTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'KC3.jpg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.AdvertisementTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'gmow.png';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.AdvertisementTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = 'HEMA.png';
            app.Button_9.Text = '';
            app.PlayButton = uibutton(app.AdvertisementTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.AdvertisementTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.AdvertisementTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.Button_10 = uibutton(app.AdvertisementTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'Kikan.jpg';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.AdvertisementTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = 'cgpersia.png';
            app.Button_11.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WatermarkUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WatermarkUIFigure)
        end
    end
end